classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        Tab                         matlab.ui.container.Tab
        GetHeating                  matlab.ui.control.Button
        HeatingSystemLabel          matlab.ui.control.Label
        GetIrrigation               matlab.ui.control.Button
        WaterTemperatureValue       matlab.ui.control.Label
        StatusIrrigationValue       matlab.ui.control.Label
        WaterTemperatureGreenhouse  matlab.ui.control.Label
        StatusIrrigation            matlab.ui.control.Label
        IrrigationSystemLabel       matlab.ui.control.Label
        LightingSystemLabel         matlab.ui.control.Label
        StatusLighting              matlab.ui.control.Label
        StatusLightingValue         matlab.ui.control.Label
        GetLighting                 matlab.ui.control.Button
        StatusVentilationValue      matlab.ui.control.Label
        HumidityValue               matlab.ui.control.Label
        StatusHeating               matlab.ui.control.Label
        TemperatureGreenhouse       matlab.ui.control.Label
        StatusHeatingValue          matlab.ui.control.Label
        TemperatureValue            matlab.ui.control.Label
        WeatherInformationLabel     matlab.ui.control.Label
        CityDropDown                matlab.ui.control.DropDown
        CityDropDownLabel           matlab.ui.control.Label
        TemperatureBox              matlab.ui.control.Label
        HumidityBox                 matlab.ui.control.Label
        WeatherBox                  matlab.ui.control.Label
        GetVentilation              matlab.ui.control.Button
        AirValue                    matlab.ui.control.Label
        AirQualityGreenhouse        matlab.ui.control.Label
        HumidityGreenhouse          matlab.ui.control.Label
        StatusVentilation           matlab.ui.control.Label
        VentilationSystemLabel      matlab.ui.control.Label
        lblDATE                     matlab.ui.control.Label
        lblTIME                     matlab.ui.control.Label
        SystemTimeandDateLabel      matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        Tab2                        matlab.ui.container.Tab
        GetButton                   matlab.ui.control.Button
        GreenhouseImageLabel        matlab.ui.control.Label
        GreenhouseImage             matlab.ui.control.Image
    end

    
 properties (Access = private)
        Timer % Timer object
    end
     methods (Access = private)
        % Code to execute when the app is deleted
        function appdelete(app)
            % Stop and delete a timer if it exists
            if isfield(app, 'Timer') && isvalid(app.Timer)
                stop(app.Timer);
                delete(app.Timer);
            end
        end

        % Update date and time
        function updateDateTime(app)
            % Get current date and time
            currentDate = datestr(now, 'dddd, mmmm dd, yyyy'); % Example: "Monday, November 24, 2024"
            currentTime = datestr(now, 'HH:MM:SS PM');         % Example: "03:45:12 PM"

            % Update the labels
            app.lblDATE.Text = ['Date: ', currentDate];
            app.lblTIME.Text = ['Time: ', currentTime];
        end

        % Fetch weather data
        function [weatherCondition, humidity, temperature] = getWeatherData(app, city)
            % Your OpenWeatherMap API key
            apiKey = 'db8f06d2060111bfa12573556286b163';

            % OpenWeatherMap API endpoint
            baseUrl = 'http://api.openweathermap.org/data/2.5/weather';

            % Construct the full API URL
            url = sprintf('%s?q=%s&units=metric&appid=%s', baseUrl, city, apiKey);

            try
                % Fetch the weather data
                response = webread(url);

                % Extract details
                weatherCondition = response.weather(1).description; % e.g., 'clear sky'
                humidity = response.main.humidity; % e.g., 60
                temperature = response.main.temp; % e.g., 22.5
            catch
                % Return empty values in case of an error
                weatherCondition = 'N/A';
                humidity = 'N/A';
                temperature = 'N/A';
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Update the date and time immediately
            updateDateTime(app);

            % Clear the weather boxes
            app.WeatherBox.Text = 'Weather: ';
            app.HumidityBox.Text = 'Humidity: ';
            app.TemperatureBox.Text = 'Temperature: ';

            % Set up a timer to update every second
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                              'Period', 1, ...
                              'TimerFcn', @(~,~) updateDateTime(app));
            start(app.Timer);
            
            
        end

        % Value changed function: CityDropDown
        function CityDropDownValueChanged(app, event)
                  % Get the selected city
    selectedCity = app.CityDropDown.Value;

    % Check if a city is selected
    if strcmp(selectedCity, 'Select a city')
        % Clear the boxes if no city is selected
        app.WeatherBox.Text = 'Weather: ';
        app.HumidityBox.Text = 'Humidity: ';
        app.TemperatureBox.Text = 'Temperature: ';
    else
        try
            % Fetch weather data for the selected city
            [weather, humidity, temperature] = app.getWeatherData(selectedCity);

            % Update the boxes with weather details
            app.WeatherBox.Text = ['Weather: ', weather];
            app.HumidityBox.Text = ['Humidity: ', num2str(humidity), '%'];
            app.TemperatureBox.Text = ['Temperature: ', num2str(temperature), '°C'];
        catch
            % Display an error message in case of failure
            app.WeatherBox.Text = 'Weather: Error fetching data';
            app.HumidityBox.Text = 'Humidity: N/A';
            app.TemperatureBox.Text = 'Temperature: N/A';
        end
    end
        end

        % Button pushed function: GetIrrigation
        function GetIrrigationButtonPushed(app, event)
            % Example: Fetch the water temperature from a mock function
            water_temperature = 22; % Replace with actual logic

            % Update the water temperature value in the label
            app.WaterTemperatureValue.Text = sprintf('%.1f °C', water_temperature);

            app.StatusIrrigationValue.Text = 'Inactive';
        end

        % Button pushed function: GetVentilation
        function GetVentilationButtonPushed(app, event)
             % Example: Fetch the humidity from a mock function
            humidity = 48; % Replace with actual logic

            % Update the humidity value in the label
            app.HumidityValue.Text = sprintf('%.1f %%', humidity);

            % Example: Fetch the AirQuality value from a mock function
            AirQuality = 89; % Replace with actual logic

            % Update the AirQuality value in the label
            app.AirValue.Text = sprintf('%.1f %%', AirQuality);

            app.StatusVentilationValue.Text = 'Inactive';
        end

        % Button pushed function: GetLighting
        function GetLightingButtonPushed(app, event)
            app.StatusLightingValue.Text = 'Active';
        end

        % Button pushed function: GetHeating
        function GetHeatingButtonPushed(app, event)
             app.StatusHeatingValue.Text = 'Inactive';

             % Example: Fetch the water temperature from a mock function
            temperature = 24; % Replace with actual logic

            % Update the water temperature value in the label
            app.TemperatureValue.Text = sprintf('%.1f °C', temperature);
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
             app.GreenhouseImage.ImageSource = 'Greenhouse.png';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'pic5.png');
            app.SystemTimeandDateLabel = uilabel(app.Tab);
            app.SystemTimeandDateLabel.Text = 'System Time and Date';
            app.lblTIME = uilabel(app.Tab);
            app.lblTIME.Text = 'Time:';
            app.lblDATE = uilabel(app.Tab);
            app.lblDATE.Text = 'Date:';
            app.VentilationSystemLabel = uilabel(app.Tab);
            app.VentilationSystemLabel.Text = 'Ventilation System';
            app.StatusVentilation = uilabel(app.Tab);
            app.StatusVentilation.Text = 'Status:';
            app.HumidityGreenhouse = uilabel(app.Tab);
            app.HumidityGreenhouse.Text = 'Humidity:';
            app.AirQualityGreenhouse = uilabel(app.Tab);
            app.AirQualityGreenhouse.Text = 'Air Quality:';
            app.AirValue = uilabel(app.Tab);
            app.AirValue.Text = '';
            app.GetVentilation = uibutton(app.Tab, 'push');
            app.GetVentilation.ButtonPushedFcn = createCallbackFcn(app, @GetVentilationButtonPushed, true);
            app.GetVentilation.Text = 'Get';
            app.WeatherBox = uilabel(app.Tab);
            app.WeatherBox.Text = 'Weather:';
            app.HumidityBox = uilabel(app.Tab);
            app.HumidityBox.Text = 'Humidity:';
            app.TemperatureBox = uilabel(app.Tab);
            app.TemperatureBox.Text = 'Temperature:';
            app.CityDropDownLabel = uilabel(app.Tab);
            app.CityDropDownLabel.Text = 'City';
            app.CityDropDown = uidropdown(app.Tab);
            app.CityDropDown.Items = {' ', 'Kayseri', 'Tokyo', 'Paris', 'Sakarya', 'Konya', 'Bursa'};
            app.CityDropDown.ValueChangedFcn = createCallbackFcn(app, @CityDropDownValueChanged, true);
            app.CityDropDown.Value = ' ';
            app.WeatherInformationLabel = uilabel(app.Tab);
            app.WeatherInformationLabel.Text = 'Weather Information';
            app.TemperatureValue = uilabel(app.Tab);
            app.TemperatureValue.Text = '';
            app.StatusHeatingValue = uilabel(app.Tab);
            app.StatusHeatingValue.Text = '';
            app.TemperatureGreenhouse = uilabel(app.Tab);
            app.TemperatureGreenhouse.Text = 'Temperature:';
            app.StatusHeating = uilabel(app.Tab);
            app.StatusHeating.Text = 'Status:';
            app.HumidityValue = uilabel(app.Tab);
            app.HumidityValue.Text = '';
            app.StatusVentilationValue = uilabel(app.Tab);
            app.StatusVentilationValue.Text = '';
            app.GetLighting = uibutton(app.Tab, 'push');
            app.GetLighting.ButtonPushedFcn = createCallbackFcn(app, @GetLightingButtonPushed, true);
            app.GetLighting.Text = 'Get';
            app.StatusLightingValue = uilabel(app.Tab);
            app.StatusLightingValue.Text = '';
            app.StatusLighting = uilabel(app.Tab);
            app.StatusLighting.Text = 'Status:';
            app.LightingSystemLabel = uilabel(app.Tab);
            app.LightingSystemLabel.Text = 'Lighting System';
            app.IrrigationSystemLabel = uilabel(app.Tab);
            app.IrrigationSystemLabel.Text = 'Irrigation System';
            app.StatusIrrigation = uilabel(app.Tab);
            app.StatusIrrigation.Text = 'Status:';
            app.WaterTemperatureGreenhouse = uilabel(app.Tab);
            app.WaterTemperatureGreenhouse.Text = 'Water Temperature:';
            app.StatusIrrigationValue = uilabel(app.Tab);
            app.StatusIrrigationValue.Text = '';
            app.WaterTemperatureValue = uilabel(app.Tab);
            app.WaterTemperatureValue.Text = '';
            app.GetIrrigation = uibutton(app.Tab, 'push');
            app.GetIrrigation.ButtonPushedFcn = createCallbackFcn(app, @GetIrrigationButtonPushed, true);
            app.GetIrrigation.Text = 'Get';
            app.HeatingSystemLabel = uilabel(app.Tab);
            app.HeatingSystemLabel.Text = 'Heating System';
            app.GetHeating = uibutton(app.Tab, 'push');
            app.GetHeating.ButtonPushedFcn = createCallbackFcn(app, @GetHeatingButtonPushed, true);
            app.GetHeating.Text = 'Get';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.GreenhouseImage = uiimage(app.Tab2);
            app.GreenhouseImageLabel = uilabel(app.Tab2);
            app.GreenhouseImageLabel.Text = 'Greenhouse Image:';
            app.GetButton = uibutton(app.Tab2, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end